classdef userInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        ExporttocsvMenu               matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        Menu_4                        matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        Menu2_3                       matlab.ui.container.Menu
        BruteforceToolLabel           matlab.ui.control.Label
        PasswordHashEditFieldLabel    matlab.ui.control.Label
        PasswordHashEditField         matlab.ui.control.EditField
        STARTBruteforceButton         matlab.ui.control.Button
        LogMonitorTextAreaLabel       matlab.ui.control.Label
        LogMonitorTextArea            matlab.ui.control.TextArea
        TodecryptDropDownLabel        matlab.ui.control.Label
        TodecryptDropDown             matlab.ui.control.DropDown
        EncryptionDropDownLabel       matlab.ui.control.Label
        EncryptionDropDown            matlab.ui.control.DropDown
        RainbowtableDropDownLabel     matlab.ui.control.Label
        RainbowtableDropDown          matlab.ui.control.DropDown
        ChooseRessourceDropDownLabel  matlab.ui.control.Label
        ChooseRessourceDropDown       matlab.ui.control.DropDown
        CPUGPUTempCTextAreaLabel      matlab.ui.control.Label
        CPUGPUTempCTextArea           matlab.ui.control.TextArea
        FanspeedrpmTextAreaLabel      matlab.ui.control.Label
        FanspeedrpmTextArea           matlab.ui.control.TextArea
        ResultTextAreaLabel           matlab.ui.control.Label
        ResultTextArea                matlab.ui.control.TextArea
        CPUCoresDropDownLabel         matlab.ui.control.Label
        CPUCoresDropDown              matlab.ui.control.DropDown
        AdvancedsettingsLabel         matlab.ui.control.Label
        EvaluateSystemButton          matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        CPUTab                        matlab.ui.container.Tab
        UIAxes_cpu1                   matlab.ui.control.UIAxes
        UIAxes_cpu2                   matlab.ui.control.UIAxes
        UIAxes_cpu3                   matlab.ui.control.UIAxes
        UIAxes_cpu4                   matlab.ui.control.UIAxes
        GPUTab                        matlab.ui.container.Tab
        UIAxes_gpu                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        messageBuffer = {128};
        cnt = 1;
    end
 
    methods (Access = private)
        %Message Buffer
        function fWriteMessageBuffer(app,message)
                app.messageBuffer{app.cnt} = message;
                app.LogMonitorTextArea.Value = app.messageBuffer; 
                app.cnt = app.cnt + 1;
        end
                
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PasswordHashEditField.Value = '--Type a password--';
            app.PasswordHashEditField.FontAngle = 'italic';
            app.STARTBruteforceButton.Enable = 'off';
        end

        % Button pushed function: EvaluateSystemButton
        function EvaluateSystemButtonPushed(app, event)
        %Start system evaluation
        fWriteMessageBuffer(app, 'System evaluation started...');
        
        %Get CPU information
        fWriteMessageBuffer(app, 'Get CPU information: ');
        fWriteMessageBuffer(app, '---------------------------------------');
         
        cpuInfo = cpuinfo();
        
        cpuMessage = sprintf('Name: \t \t \t %s' , cpuInfo.Name); 
        fWriteMessageBuffer(app, cpuMessage);
        
        cpuCores = num2str(cpuInfo.NumProcessors);
        cpuMessage = sprintf('Number of cores: \t %s', cpuCores);
        fWriteMessageBuffer(app, cpuMessage);
        
        cpuMessage = sprintf('Clock: \t \t \t %s', cpuInfo.Clock);
        fWriteMessageBuffer(app, cpuMessage);
        
        cpuMessage = sprintf('OS Type: \t \t %s (%s)', cpuInfo.OSType, cpuInfo.OSVersion);
        fWriteMessageBuffer(app, cpuMessage);
        fWriteMessageBuffer(app, '---------------------------------------');
        
        %Set value for "CPU cores" DropDown
        switch cpuInfo.NumProcessors
            case 1
                app.CPUCoresDropDown.Items = {'1'};
            case 2
                app.CPUCoresDropDown.Items = {'1', '2'};
            case 3
                app.CPUCoresDropDown.Items = {'1', '2', '3'};
            case 4
                app.CPUCoresDropDown.Items = {'1', '2', '3', '4'};
        end

            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            exitBox = questdlg('Do you really want to exit?','Warning');
            
            switch exitBox
                case 'Yes'
                    app.delete;
                case 'No'
                    
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox({'Name: Brute-Force Tool' 'Version: 0.0.0' 'Designer: A.Gonzalez / B. Huerzeler'}, 'About...');   
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
        
        end

        % Value changed function: ChooseRessourceDropDown
        function ChooseRessourceDropDownValueChanged(app, event)
            value = app.ChooseRessourceDropDown.Value;
            if value == 'CPU'
                app.CPUCoresDropDown.Enable = 'on';
            else
                app.CPUCoresDropDown.Items = {'1'};
                app.CPUCoresDropDown.Enable = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure;
            app.MainUIFigure.Name = 'Main';
            app.FileMenu = uimenu(app.MainUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.ExporttocsvMenu = uimenu(app.FileMenu);
            app.ExporttocsvMenu.Text = 'Export to csv...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.Menu_4 = uimenu(app.MainUIFigure);
            app.Menu_4.Text = '?';
            app.AboutMenu = uimenu(app.Menu_4);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About...';
            app.Menu2_3 = uimenu(app.Menu_4);
            app.Menu2_3.Text = 'Menu2';
            app.BruteforceToolLabel = uilabel(app.MainUIFigure);
            app.BruteforceToolLabel.Text = 'Brute force Tool';
            app.PasswordHashEditFieldLabel = uilabel(app.MainUIFigure);
            app.PasswordHashEditFieldLabel.Text = 'Password / Hash';
            app.PasswordHashEditField = uieditfield(app.MainUIFigure, 'text');
            app.STARTBruteforceButton = uibutton(app.MainUIFigure, 'push');
            app.STARTBruteforceButton.Text = 'START Brute force';
            app.LogMonitorTextAreaLabel = uilabel(app.MainUIFigure);
            app.LogMonitorTextAreaLabel.Text = 'Log-Monitor';
            app.LogMonitorTextArea = uitextarea(app.MainUIFigure);
            app.LogMonitorTextArea.Editable = 'off';
            app.TodecryptDropDownLabel = uilabel(app.MainUIFigure);
            app.TodecryptDropDownLabel.Text = 'To decrypt';
            app.TodecryptDropDown = uidropdown(app.MainUIFigure);
            app.TodecryptDropDown.Items = {'Select...', 'Password', 'Hash'};
            app.TodecryptDropDown.Value = 'Select...';
            app.EncryptionDropDownLabel = uilabel(app.MainUIFigure);
            app.EncryptionDropDownLabel.Text = 'Encryption';
            app.EncryptionDropDown = uidropdown(app.MainUIFigure);
            app.EncryptionDropDown.Items = {'Select...', 'RSA', 'SHA'};
            app.EncryptionDropDown.Value = 'RSA';
            app.RainbowtableDropDownLabel = uilabel(app.MainUIFigure);
            app.RainbowtableDropDownLabel.Text = 'Rainbowtable';
            app.RainbowtableDropDown = uidropdown(app.MainUIFigure);
            app.RainbowtableDropDown.Items = {'Select...', 'Yes', 'No'};
            app.RainbowtableDropDown.Value = 'Yes';
            app.ChooseRessourceDropDownLabel = uilabel(app.MainUIFigure);
            app.ChooseRessourceDropDownLabel.Text = 'Choose Ressource';
            app.ChooseRessourceDropDown = uidropdown(app.MainUIFigure);
            app.ChooseRessourceDropDown.Items = {'CPU', 'GPU', 'Option 3', 'Option 4'};
            app.ChooseRessourceDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseRessourceDropDownValueChanged, true);
            app.ChooseRessourceDropDown.Value = 'CPU';
            app.CPUGPUTempCTextAreaLabel = uilabel(app.MainUIFigure);
            app.CPUGPUTempCTextAreaLabel.Text = 'CPU / GPU Temp. [°C]';
            app.CPUGPUTempCTextArea = uitextarea(app.MainUIFigure);
            app.CPUGPUTempCTextArea.Editable = 'off';
            app.FanspeedrpmTextAreaLabel = uilabel(app.MainUIFigure);
            app.FanspeedrpmTextAreaLabel.Text = 'Fan speed [rpm]';
            app.FanspeedrpmTextArea = uitextarea(app.MainUIFigure);
            app.FanspeedrpmTextArea.Editable = 'off';
            app.ResultTextAreaLabel = uilabel(app.MainUIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.MainUIFigure);
            app.ResultTextArea.Editable = 'off';
            app.ResultTextArea.Value = {'01234567'};
            app.CPUCoresDropDownLabel = uilabel(app.MainUIFigure);
            app.CPUCoresDropDownLabel.Text = 'CPU Cores';
            app.CPUCoresDropDown = uidropdown(app.MainUIFigure);
            app.CPUCoresDropDown.Items = {};
            app.CPUCoresDropDown.Value = {};
            app.AdvancedsettingsLabel = uilabel(app.MainUIFigure);
            app.AdvancedsettingsLabel.Text = 'Advanced settings';
            app.EvaluateSystemButton = uibutton(app.MainUIFigure, 'push');
            app.EvaluateSystemButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateSystemButtonPushed, true);
            app.EvaluateSystemButton.Text = 'Evaluate System';
            app.TabGroup = uitabgroup(app.MainUIFigure);
            app.CPUTab = uitab(app.TabGroup);
            app.CPUTab.Title = 'CPU';
            app.UIAxes_cpu1 = uiaxes(app.CPUTab);
            title(app.UIAxes_cpu1, 'CPU 1')
            ylabel(app.UIAxes_cpu1, 'Y')
            app.UIAxes_cpu1.XLim = [0 Inf];
            app.UIAxes_cpu1.YLim = [0 100];
            app.UIAxes_cpu1.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes_cpu1.YTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes_cpu2 = uiaxes(app.CPUTab);
            title(app.UIAxes_cpu2, 'CPU 2')
            ylabel(app.UIAxes_cpu2, 'Y')
            app.UIAxes_cpu2.XLim = [0 Inf];
            app.UIAxes_cpu2.YLim = [0 100];
            app.UIAxes_cpu2.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes_cpu2.YTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes_cpu3 = uiaxes(app.CPUTab);
            title(app.UIAxes_cpu3, 'CPU 3')
            ylabel(app.UIAxes_cpu3, 'Y')
            app.UIAxes_cpu3.XLim = [0 Inf];
            app.UIAxes_cpu3.YLim = [0 100];
            app.UIAxes_cpu3.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes_cpu3.YTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes_cpu4 = uiaxes(app.CPUTab);
            title(app.UIAxes_cpu4, 'CPU 4')
            ylabel(app.UIAxes_cpu4, 'Y')
            app.UIAxes_cpu4.XLim = [0 Inf];
            app.UIAxes_cpu4.YLim = [0 100];
            app.UIAxes_cpu4.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes_cpu4.YTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.GPUTab = uitab(app.TabGroup);
            app.GPUTab.Title = 'GPU';
            app.UIAxes_gpu = uiaxes(app.GPUTab);
            title(app.UIAxes_gpu, 'GPU')
            ylabel(app.UIAxes_gpu, 'Y')
            app.UIAxes_gpu.XLim = [0 Inf];
            app.UIAxes_gpu.YLim = [0 100];
            app.UIAxes_gpu.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes_gpu.YTick = [0 10 20 30 40 50 60 70 80 90 100];
        end
    end

    methods (Access = public)

        % Construct app
        function app = userInterface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end